classdef HealthVision < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HealthVisionUIFigure      matlab.ui.Figure
        HeaderPanel               matlab.ui.container.Panel
        HeaderUserEditField       matlab.ui.control.EditField
        HeaderUserLabel           matlab.ui.control.Label
        HeaderTitleAxes           matlab.ui.control.UIAxes
        HeaderUserDetailsButton   matlab.ui.control.Button
        MainPanel                 matlab.ui.container.Panel
        ButtonPanel               matlab.ui.container.Panel
        ECGButton                 matlab.ui.control.StateButton
        PPGLabel                  matlab.ui.control.Label
        PPGButton                 matlab.ui.control.StateButton
        StepsLabel                matlab.ui.control.Label
        StepsButton               matlab.ui.control.StateButton
        ECGLabel                  matlab.ui.control.Label
        InfoPanel                 matlab.ui.container.Panel
        PPGPanel                  matlab.ui.container.Panel
        PPGAxesDetail1            matlab.ui.control.UIAxes
        PPGAxesDetail3            matlab.ui.control.UIAxes
        PPGLowLamp                matlab.ui.control.Lamp
        PPGNormalLamp             matlab.ui.control.Lamp
        PPGStatisticsButtonGroup  matlab.ui.container.ButtonGroup
        PPGButton30m              matlab.ui.control.RadioButton
        PPGButton2h               matlab.ui.control.RadioButton
        PPGButton6h               matlab.ui.control.RadioButton
        PPGLastTimeLabel          matlab.ui.control.Label
        PPGMinEditField           matlab.ui.control.NumericEditField
        PPGAvgEditField           matlab.ui.control.NumericEditField
        PPGAxesDetail2            matlab.ui.control.UIAxes
        PPGMaxEditField           matlab.ui.control.NumericEditField
        PPGMinLabel               matlab.ui.control.Label
        PPGMaxLabel               matlab.ui.control.Label
        PPGAvgLabel               matlab.ui.control.Label
        PPGLowLabel               matlab.ui.control.Label
        PPGNormalLabel            matlab.ui.control.Label
        PPGGauge                  matlab.ui.control.LinearGauge
        ECGPanel                  matlab.ui.container.Panel
        ECGAvgEditField           matlab.ui.control.NumericEditField
        ECGLastTimeLabel          matlab.ui.control.Label
        ECGMinEditField           matlab.ui.control.NumericEditField
        ECGMaxEditField           matlab.ui.control.NumericEditField
        ECGLowLamp                matlab.ui.control.Lamp
        ECGNormalLamp             matlab.ui.control.Lamp
        ECGHighLamp               matlab.ui.control.Lamp
        ECGAxesDetail             matlab.ui.control.UIAxes
        ECGMinLabel               matlab.ui.control.Label
        ECGMaxLabel               matlab.ui.control.Label
        ECGAvgLabel               matlab.ui.control.Label
        ECGLowLabel               matlab.ui.control.Label
        ECGNormalLabel            matlab.ui.control.Label
        ECGHighLabel              matlab.ui.control.Label
        ECGStatisticsButtonGroup  matlab.ui.container.ButtonGroup
        ECGButton30m              matlab.ui.control.RadioButton
        ECGButton2h               matlab.ui.control.RadioButton
        ECGButton6h               matlab.ui.control.RadioButton
        Label                     matlab.ui.control.Label
        ECGGauge                  matlab.ui.control.SemicircularGauge
        GraphPanel                matlab.ui.container.Panel
        ECGAxesHome               matlab.ui.control.UIAxes
        PPGAxesHome               matlab.ui.control.UIAxes
        StepsAxesHome             matlab.ui.control.UIAxes
        StepsPanel                matlab.ui.container.Panel
        StepsGauge                matlab.ui.control.Gauge
        StepsDailyGoalLabel       matlab.ui.control.Label
        StepsAxesDetail1          matlab.ui.control.UIAxes
        StepsLampLB               matlab.ui.control.Lamp
        StepsLampLF               matlab.ui.control.Lamp
        StepsLampRB               matlab.ui.control.Lamp
        StepsLampRF               matlab.ui.control.Lamp
        StepsAxesDetail2          matlab.ui.control.UIAxes
        StepsProgressLabel        matlab.ui.control.Label
        UserDetailsPanel          matlab.ui.container.Panel
        UserTitleLabel            matlab.ui.control.Label
        UserNameLabel             matlab.ui.control.Label
        UserNameEditField         matlab.ui.control.EditField
        UserAgeLabel              matlab.ui.control.Label
        UserHeightLabel           matlab.ui.control.Label
        UserWeightLabel           matlab.ui.control.Label
        UserBMILabel              matlab.ui.control.Label
        UserXButton               matlab.ui.control.Button
        UserSaveButton            matlab.ui.control.Button
        UserAgeEditField          matlab.ui.control.NumericEditField
        UserHeightEditField       matlab.ui.control.NumericEditField
        UserWeightEditField       matlab.ui.control.NumericEditField
        SelectUserPanel           matlab.ui.container.Panel
        SelectUserListBox         matlab.ui.control.ListBox
        SelectUserLabel           matlab.ui.control.Label
    end


    properties (Access = public)
        userName = [];    % Name of the user
        userAge = 0;     % Age of the user
        userHeight = 0;  % Height of the user
        userWeight = 0;  % Weight of the user
     
    end


    methods (Access = private)

        % Value changed function: ECGButton
        function ECGButtonValueChanged(app, event)
            value = app.ECGButton.Value;
            if value == true
                app.ECGButton.BackgroundColor = [0.8, 0.8, 0.8];
                app.PPGButton.BackgroundColor = [0.15, 0.18, 0.23];
                app.StepsButton.BackgroundColor = [0.15, 0.18, 0.23];
                
                app.PPGButton.Value = false;
                app.StepsButton.Value = false;
                
                app.GraphPanel.Visible = 'off';
                app.PPGPanel.Visible = 'off';
                app.StepsPanel.Visible = 'off';
                
                app.ECGPanel.Visible = 'on';
            else
                app.ECGButton.BackgroundColor = [0.15, 0.18, 0.23];
                
                app.ECGPanel.Visible = 'off';
                app.GraphPanel.Visible = 'on';
            end
        end

        % Selection changed function: ECGStatisticsButtonGroup
        function ECGStatisticsButtonGroupSelectionChanged(app, event)
            selectedButton = app.ECGStatisticsButtonGroup.SelectedObject;
            if app.ECGButton30m.Value == true
                app.ECGLastTimeLabel.Text = 'Last 30 mins:';
            elseif app.ECGButton2h.Value == true
                app.ECGLastTimeLabel.Text = 'Last 2 hrs:';
            else
                app.ECGLastTimeLabel.Text = 'Last 6 hrs:';
            end 
        end

        % Button pushed function: HeaderUserDetailsButton
        function HeaderUserDetailsButtonPushed(app, event)
            position = app.HealthVisionUIFigure.Position;
            
            w = position(3);
            h = position(4);
            
            userDetailsWidth = 340;
            userDetailsHeight = 300;
            userDetailsX = w/2 - userDetailsWidth/2;
            userDetailsY = h/2 - userDetailsHeight/2;
            
            app.UserDetailsPanel.Position = uint16([userDetailsX,userDetailsY,userDetailsWidth,userDetailsHeight]);
            
            
            if(strcmp(app.userName, ''))
                app.UserNameEditField.Value = '';
            else
                app.UserNameEditField.Value = app.userName;
            end
            
            app.UserAgeEditField.Value = app.userAge;
            app.UserHeightEditField.Value = app.userHeight;
            app.UserWeightEditField.Value = app.userWeight;
            app.UserBMILabel.Text = [ 'BMI: ' num2str(round(app.userWeight / app.userHeight^2,2)) ];
            
            app.UserDetailsPanel.Visible = 'on';
            
        end

        % Size changed function: HealthVisionUIFigure
        function HealthVisionUIFigureSizeChanged(app, event)
            position = app.HealthVisionUIFigure.Position;
            
            w = position(3);
            h = position(4);
            
            % Automatically adjust GUI size
            
            
            % GUI size factor
            GUI_x = 0.0/1200.0;
            GUI_w = 1200.0/1200.0;
            GUI_y = 0.0/700.0;
            GUI_h = 700.0/700.0;
            
            
            % Header size factor
            Header_x = 0.0/1200.0;
            Header_w = 1200.0/1200.0;
            Header_y = 600.0/700.0;
            Header_h = 100.0/700.0;
            
            
            % Header Panel components
            Title_width = 500;
            Title_height = 100;
            Title_xpos = Header_w*w/2 - Title_width/2;
            Title_ypos = Header_h*h/2 - Title_height/2;
            
            UserEF_xpos = 100;
            UserEF_ypos = Header_h*h/2 - 15;
            UserEF_width = 200;
            UserEF_height = 40;
            
            UserL_width = 65;
            UserL_height = 40;
            UserL_xpos = UserEF_xpos - UserL_width;
            UserL_ypos = UserEF_ypos;
            
            inputEF_ySpace = 10;
            
            inputL_width = 80;
            inputL_height = 32;
            inputEF_width = 100;
            inputEF_height = 32;
            
            inputL_xpos = max([UserEF_xpos + UserEF_width, w - inputL_width - inputEF_width - 25]);
            inputL_ypos = Header_h*h/2 + inputEF_ySpace + inputL_height/2;
            inputEF_xpos = inputL_xpos + inputL_width;
            inputEF_ypos = Header_h*h/2 + inputEF_ySpace + inputEF_height/2;
            
            userDet_x = 1075.0/1200.0;
            userDet_w = 110.0/1200.0;
            userDet_y = 15.0/700.0;
            userDet_h = 75.0/700.0;
            
            
            % Main Panel size factor
            Main_x= 0.0/1200.0;
            Main_w = 1200.0/1200.0;
            Main_y= 0.0/700.0;
            Main_h = 600.0/700.0;
            
            
            % Button Panel size factor
            Button_x = 0.0/1200.0;
            Button_w = 220.0/1200.0;
            but_L_x = 10.0/1200.0;
            but_L_w = 80.0/1200.0;
            but_B_x = 85.0/1200.0;
            but_B_w = 125.0/1200.0;
            
            ySpace = 200.0/700.0;
            
            Button_y = 0.0/700.0;
            Button_h = 600.0/700.0;
            but_L_y = 80.0/700.0;
            but_L_h = 48.0/700.0;
            but_B_y = 50.0/700.0;
            but_B_h = 125.0/700.0;
            
            
            % Info Panel size factor
            Info_x = 220.0/1200.0;
            Info_w = 980.0/1200.0;
            Info_y = 0.0/700.0;
            Info_h = 600.0/700.0;
            
            GraphPanel_x = 0.0/1200.0;
            GraphPanel_w = 980.0/1200.0;
            GraphPanel_y = 0.0/700.0;
            GraphPanel_h = 600.0/700.0;
            
            PPGPanel_x = 25.0/1200.0;
            PPGPanel_w = 930.0/1200.0;
            PPGPanel_y = 40.0/700.0;
            PPGPanel_h = 520.0/700.0;
            
            ECGPanel_x = 40.0/1200.0;
            ECGPanel_w = 900.0/1200.0;
            ECGPanel_y = 90.0/700.0;
            ECGPanel_h = 420.0/700.0;
            
            StepsPanel_x = 100.0/1200.0;
            StepsPanel_w = 780.0/1200.0;
            StepsPanel_y = 60.0/700.0;
            StepsPanel_h = 480.0/700.0;


            % Graph
            
            graph_x = 15.0/1200.0;
            graph_w = 950.0/1200.0;
            graph_y = 25.0/700.0;
            graph_h = 175.0/700.0;
            
            
            % PPG
            PPG_UI3_x = 0.0/1200.0;
            PPG_UI3_w = 600.0/1200.0;
            PPG_UI3_y = 400.0/700.0;
            PPG_UI3_h = 120.0/700.0;
            
            PPG_UI4_x = 0.0/1200.0;
            PPG_UI4_w = 600.0/1200.0;
            PPG_UI4_y = 270.0/700.0;
            PPG_UI4_h = 120.0/700.0;
            
            PPG_UI6_x = 340.0/1200.0;
            PPG_UI6_w = 550.0/1200.0;
            PPG_UI6_y = 10.0/700.0;
            PPG_UI6_h = 200.0/700.0;
            
            %PPG_SPO2EF_x = 700.0/1200.0;
            %PPG_SPO2EF_w = 80.0/1200.0;
            %PPG_SPO2EF_y = 250.0/700.0;
            %PPG_SPO2EF_h =  80.0/700.0;
            
            PPG_MinEF_x = 210.0/1200.0;
            PPG_MinEF_w = 100.0/1200.0;
            PPG_MinEF_y = 125.0/700.0;
            PPG_MinEF_h = 30.0/700.0;
            
            PPG_MinL_x = 150.0/1200.0;
            PPG_MinL_w = 75.0/1200.0;
            PPG_MinL_y = 125.0/700.0;
            PPG_MinL_h = 32.0/700.0;
            
            PPG_AvgEF_x = 210.0/1200.0;
            PPG_AvgEF_w = 100.0/1200.0;
            PPG_AvgEF_y = 55.0/700.0;
            PPG_AvgEF_h = 30.0/700.0;
            
            PPG_AvgL_x = 150.0/1200.0;
            PPG_AvgL_w = 75.0/1200.0;
            PPG_AvgL_y = 55.0/700.0;
            PPG_AvgL_h = 32.0/700.0;
            
            PPG_MaxEF_x = 210.0/1200.0;
            PPG_MaxEF_w = 100.0/1200.0;
            PPG_MaxEF_y = 90.0/700.0;
            PPG_MaxEF_h = 30.0/700.0;
            
            PPG_MaxL_x = 150.0/1200.0;
            PPG_MaxL_w = 75.0/1200.0;
            PPG_MaxL_y = 90.0/700.0;
            PPG_MaxL_h = 32.0/700.0;
            
            %PPG_LowLamp_x = 880.0/1200.0;
            %PPG_LowLamp_w = 25.0/1200.0;
            %PPG_LowLamp_y = 300.0/700.0;
            %PPG_LowLamp_h = 25.0/700.0;
            
            PPG_LowL_x = 700.0/1200.0;
            PPG_LowL_w = 250.0/1200.0;
            PPG_LowL_y = 355.0/700.0;
            PPG_LowL_h = 32.0/700.0;
            
            %PPG_NormalLamp_x = 880.0/1200.0;
            %PPG_NormalLamp_w = 25.0/1200.0;
            %PPG_NormalLamp_y = 255.0/700.0;
            %PPG_NormalLamp_h = 25.0/700.0;
                        
            PPG_NormalL_x = 700.0/1200.0;
            PPG_NormalL_w = 250.0/1200.0;
            PPG_NormalL_y = 325.0/700.0;
            PPG_NormalL_h = 32.0/700.0;
            
            PPG_Gauge_x = 635.0/1200.0;
            PPG_Gauge_w = 250.0/1200.0;
            PPG_Gauge_y = 415.0/700.0;
            PPG_Gauge_h = 40.0/700.0;
            
            PPG_StatisticsBG_x = 10.0/1200.0;
            PPG_StatisticsBG_w = 100.0/1200.0;
            PPG_StatisticsBG_y = 65.0/700.0;
            PPG_StatisticsBG_h = 120.0/700.0;
            
            PPG_LastTime_x = 150.0/1200.0;
            PPG_LastTime_w = 175.0/1200.0;
            PPG_LastTime_y = 165.0/700.0;
            PPG_LastTime_h = 32.0/700.0;
           
      
            % ECG
            ECG_AvgEF_x = 300.0/1200.0;
            ECG_AvgEF_w = 100.0/1200.0;
            ECG_AvgEF_y = 265.0/700.0;
            ECG_AvgEF_h = 30.0/700.0;
            
            ECG_AvgL_x = 250.0/1200.0;
            ECG_AvgL_w = 75.0/1200.0;
            ECG_AvgL_y = 265.0/700.0;
            ECG_AvgL_h = 32.0/700.0;
            
            ECG_MinEF_x = 300.0/1200.0;
            ECG_MinEF_w = 100.0/1200.0;
            ECG_MinEF_y = 345.0/700.0;
            ECG_MinEF_h = 30.0/700.0;
            
            ECG_MinL_x = 250.0/1200.0;
            ECG_MinL_w = 75.0/1200.0;
            ECG_MinL_y = 345.0/700.0;
            ECG_MinL_h = 32.0/700.0;
            
            ECG_MaxEF_x = 300.0/1200.0;
            ECG_MaxEF_w = 100.0/1200.0;
            ECG_MaxEF_y = 305.0/700.0;
            ECG_MaxEF_h = 30.0/700.0;
            
            ECG_MaxL_x = 250.0/1200.0;
            ECG_MaxL_w = 75.0/1200.0;
            ECG_MaxL_y = 305.0/700.0;
            ECG_MaxL_h = 32.0/700.0;
            
            ECG_Gauge_x = 475.0/1200.0;
            ECG_Gauge_w = 220.0/1200.0;
            ECG_Gauge_y = 265.0/700.0;
            ECG_Gauge_h = 120.0/700.0;
            
            %ECG_Low_x = 825.0/1200.0;
            %ECG_Low_w = 25.0/1200.0;
            %ECG_Low_y = 265.0/700.0;
            %ECG_Low_h = 25.0/700.0;
            
            ECG_LowL_x = 740.0/1200.0;
            ECG_LowL_w = 150.0/1200.0;
            ECG_LowL_y = 335.0/700.0;
            ECG_LowL_h = 32.0/700.0;
            
            %ECG_Normal_x = 825.0/1200.0;
            %ECG_Normal_w = 25.0/1200.0;
            %ECG_Normal_y = 225.0/700.0;
            %ECG_Normal_h = 25.0/700.0;
            
            ECG_NormalL_x = 740.0/1200.0;
            ECG_NormalL_w = 150.0/1200.0;
            ECG_NormalL_y = 305.0/700.0;
            ECG_NormalL_h = 32.0/700.0;
            
            %ECG_High_x = 825.0/1200.0;
            %ECG_High_w = 25.0/1200.0;
            %ECG_High_y = 180.0/700.0;
            %ECG_High_h = 25.0/700.0;
            
            ECG_HighL_x = 740.0/1200.0;
            ECG_HighL_w = 150.0/1200.0;
            ECG_HighL_y = 275.0/700.0;
            ECG_HighL_h = 32.0/700.0;
            
            ECG_LastTimeL_x = 250.0/1200.0;
            ECG_LastTimeL_w = 175.0/1200.0;
            ECG_LastTimeL_y = 385.0/700.0;
            ECG_LastTimeL_h = 32.0/700.0;
            
            ECG_AxesDetail_x = 40.0/1200.0;
            ECG_AxesDetail_w = 830.0/1200.0;
            ECG_AxesDetail_y = 0.0/700.0;
            ECG_AxesDetail_h = 250.0/700.0;
            
            ECG_Statistics_x = 32.0/1200.0;
            ECG_Statistics_w = 130.0/1200.0;
            ECG_Statistics_y = 265.0/700.0;
            ECG_Statistics_h = 120.0/700.0;
            
            
            % Steps 
            Steps_Gauge_x = 60.0/1200.0;
            Steps_Gauge_w = 200.0/1200.0;
            Steps_Gauge_y = 150.0/700.0;
            Steps_Gauge_h = 200.0/700.0;
            
            Steps_DailyGoalL_x = 90.0/1200.0;
            Steps_DailyGoalL_w = 200.0/1200.0;
            Steps_DailyGoalL_y = 100.0/700.0;
            Steps_DailyGoalL_h = 30.0/700.0;
            
            Steps_Axes1_x = 305.0/1200.0;
            Steps_Axes1_w = 400.0/1200.0;
            Steps_Axes1_y = 70.0/700.0;
            Steps_Axes1_h = 400.0/700.0;
            
            Steps_Axes2_x = 280.0/1200.0;
            Steps_Axes2_w = 450.0/1200.0;
            Steps_Axes2_y = 0.0/700.0;
            Steps_Axes2_h = 100.0/700.0;
            
            Steps_ProgressL_x = 100.0/1200.0;
            Steps_ProgressL_w = 200.0/1200.0;
            Steps_ProgressL_y = 365.0/700.0;
            Steps_ProgressL_h = 32.0/700.0;
            
            Steps_LampLB_x = 390.0/1200.0;
            Steps_LampLB_w = 75.0/1200.0;
            Steps_LampLB_y = 125.0/700.0;
            Steps_LampLB_h = 75.0/700.0;
           
            Steps_LampLF_x = 375.0/1200.0;
            Steps_LampLF_w = 85.0/1200.0;
            Steps_LampLF_y = 255.0/700.0;
            Steps_LampLF_h = 85.0/700.0;
            
            Steps_LampRB_x = 535.0/1200.0;
            Steps_LampRB_w = 75.0/1200.0;
            Steps_LampRB_y = 160.0/700.0;
            Steps_LampRB_h = 75.0/700.0;
            
            Steps_LampRF_x = 540.0/1200.0;
            Steps_LampRF_w = 85.0/1200.0;
            Steps_LampRF_y = 300.0/700.0;
            Steps_LampRF_h = 85.0/700.0;


            % Set the positions
            
            % 1) Header Panel
            app.HeaderPanel.Position = uint16([Header_x*w,Header_y*h,Header_w*w,Header_h*h]);
            
            app.HeaderTitleAxes.Position = uint16([Title_xpos, Title_ypos, Title_width, Title_height]);
            
            app.HeaderUserEditField.Position = uint16([UserEF_xpos, UserEF_ypos, UserEF_width, UserEF_height]);
            app.HeaderUserLabel.Position = uint16([UserL_xpos, UserL_ypos, UserL_width, UserL_height]);
            
            app.HeaderUserDetailsButton.Position = uint16([userDet_x*w,userDet_y*h,userDet_w*w,userDet_h*h]);
            
            %app.HeaderAgeEditField.Position = uint16([inputEF_xpos, inputEF_ypos, inputEF_width, inputEF_height]);
            %app.HeaderHeightEditField.Position = uint16([inputEF_xpos, inputEF_ypos - inputEF_ySpace - inputEF_height, inputEF_width, inputEF_height]);
            %app.HeaderWeightEditField.Position = uint16([inputEF_xpos, inputEF_ypos - 2*inputEF_ySpace - 2*inputEF_height, inputEF_width, inputEF_height]);
            
            %app.HeaderAgeLabel.Position = uint16([inputL_xpos, inputL_ypos, inputL_width, inputL_height]);
            %app.HeaderHeightLabel.Position = uint16([inputL_xpos, inputL_ypos - inputEF_ySpace - inputL_height, inputL_width, inputL_height]);
            %app.HeaderWeightLabel.Position = uint16([inputL_xpos, inputL_ypos - 2*inputEF_ySpace - 2*inputL_height, inputL_width, inputL_height]);
            
            % 2) Button Panel
            app.MainPanel.Position = uint16([Main_x*w,Main_y*h,Main_w*w,Main_h*h]);
            app.ButtonPanel.Position = uint16([Button_x*w,Button_y*h,Button_w*w,Button_h*h]);
            app.ECGButton.Position = uint16([but_B_x*w,(but_B_y+2*ySpace)*h,but_B_w*w,but_B_h*h]);
            app.ECGLabel.Position = uint16([but_L_x*w,(but_L_y+2*ySpace)*h,but_L_w*w,but_L_h*h]);
            app.PPGButton.Position = uint16([but_B_x*w,(but_B_y+ySpace)*h,but_B_w*w,but_B_h*h]);
            app.PPGLabel.Position = uint16([but_L_x*w,(but_L_y+ySpace)*h,but_L_w*w,but_L_h*h]);
            app.StepsButton.Position = uint16([but_B_x*w,but_B_y*h,but_B_w*w,but_B_h*h]);
            app.StepsLabel.Position = uint16([but_L_x*w,but_L_y*h,but_L_w*w,but_L_h*h]);
            
            % 3) Visual Info Panel
            app.InfoPanel.Position = uint16([Info_x*w,Info_y*h,Info_w*w,Info_h*h]);
            
            % 3.1) Graph Panel
            app.GraphPanel.Position = uint16([GraphPanel_x*w,GraphPanel_y*h,GraphPanel_w*w,GraphPanel_h*h]);
            app.ECGAxesHome.Position = uint16([graph_x*w,(graph_y+2*ySpace)*h,graph_w*w,graph_h*h]);
            app.PPGAxesHome.Position = uint16([graph_x*w,(graph_y+ySpace)*h,graph_w*w,graph_h*h]);
            app.StepsAxesHome.Position = uint16([graph_x*w,graph_y*h,graph_w*w,graph_h*h]);    
            
            % 3.2) PPG Panel
            app.PPGPanel.Position = uint16([PPGPanel_x*w,PPGPanel_y*h,PPGPanel_w*w,PPGPanel_h*h]);
            app.PPGAxesDetail1.Position = uint16([PPG_UI3_x*w,PPG_UI3_y*h,PPG_UI3_w*w,PPG_UI3_h*h]);
            app.PPGAxesDetail2.Position = uint16([PPG_UI4_x*w,PPG_UI4_y*h,PPG_UI4_w*w,PPG_UI4_h*h]);
            app.PPGAxesDetail3.Position = uint16([PPG_UI6_x*w,PPG_UI6_y*h,PPG_UI6_w*w,PPG_UI6_h*h]);
            %app.SPO2ValueEditField.Position = uint16([PPG_SPO2EF_x*w,PPG_SPO2EF_y*h,PPG_SPO2EF_w*w,PPG_SPO2EF_h*h]);
            app.PPGMinEditField.Position = uint16([PPG_MinEF_x*w,PPG_MinEF_y*h,PPG_MinEF_w*w,PPG_MinEF_h*h]);
            app.PPGAvgEditField.Position = uint16([PPG_AvgEF_x*w,PPG_AvgEF_y*h,PPG_AvgEF_w*w,PPG_AvgEF_h*h]);
            app.PPGMaxEditField.Position = uint16([PPG_MaxEF_x*w,PPG_MaxEF_y*h,PPG_MaxEF_w*w,PPG_MaxEF_h*h]);
            app.PPGMinLabel.Position = uint16([PPG_MinL_x*w,PPG_MinL_y*h,PPG_MinL_w*w,PPG_MinL_h*h]);
            app.PPGAvgLabel.Position = uint16([PPG_AvgL_x*w,PPG_AvgL_y*h,PPG_AvgL_w*w,PPG_AvgL_h*h]);
            app.PPGMaxLabel.Position = uint16([PPG_MaxL_x*w,PPG_MaxL_y*h,PPG_MaxL_w*w,PPG_MaxL_h*h]);
            %app.PPGLowLamp.Position = uint16([PPG_LowLamp_x*w,PPG_LowLamp_y*h,PPG_LowLamp_w*w,PPG_LowLamp_h*h]);
            %app.PPGNormalLamp.Position = uint16([PPG_NormalLamp_x*w,PPG_NormalLamp_y*h,PPG_NormalLamp_w*w,PPG_NormalLamp_h*h]);
            app.PPGLowLabel.Position = uint16([PPG_LowL_x*w,PPG_LowL_y*h,PPG_LowL_w*w,PPG_LowL_h*h]);
            app.PPGNormalLabel.Position = uint16([PPG_NormalL_x*w,PPG_NormalL_y*h,PPG_NormalL_w*w,PPG_NormalL_h*h]);
            app.PPGGauge.Position = uint16([PPG_Gauge_x*w,PPG_Gauge_y*h,PPG_Gauge_w*w,PPG_Gauge_h*h]);
            app.PPGStatisticsButtonGroup.Position = uint16([PPG_StatisticsBG_x*w,PPG_StatisticsBG_y*h,PPG_StatisticsBG_w*w,PPG_StatisticsBG_h*h]);
            app.PPGLastTimeLabel.Position = uint16([PPG_LastTime_x*w,PPG_LastTime_y*h,PPG_LastTime_w*w,PPG_LastTime_h*h]);
            
            % 3.3) ECG Panel
            app.ECGPanel.Position = uint16([ECGPanel_x*w,ECGPanel_y*h,ECGPanel_w*w,ECGPanel_h*h]);
            app.ECGAvgEditField.Position = uint16([ECG_AvgEF_x*w,ECG_AvgEF_y*h,ECG_AvgEF_w*w,ECG_AvgEF_h*h]);
            app.ECGMinEditField.Position = uint16([ECG_MinEF_x*w,ECG_MinEF_y*h,ECG_MinEF_w*w,ECG_MinEF_h*h]);
            app.ECGMaxEditField.Position = uint16([ECG_MaxEF_x*w,ECG_MaxEF_y*h,ECG_MaxEF_w*w,ECG_MaxEF_h*h]);
            app.ECGAvgLabel.Position = uint16([ECG_AvgL_x*w,ECG_AvgL_y*h,ECG_AvgL_w*w,ECG_AvgL_h*h]);
            app.ECGMinLabel.Position = uint16([ECG_MinL_x*w,ECG_MinL_y*h,ECG_MinL_w*w,ECG_MinL_h*h]);
            app.ECGMaxLabel.Position = uint16([ECG_MaxL_x*w,ECG_MaxL_y*h,ECG_MaxL_w*w,ECG_MaxL_h*h]);
            app.ECGGauge.Position = uint16([ECG_Gauge_x*w,ECG_Gauge_y*h,ECG_Gauge_w*w,ECG_Gauge_h*h]);
            %app.ECGLowLamp.Position = uint16([ECG_Low_x*w,ECG_Low_y*h,ECG_Low_w*w,ECG_Low_h*h]);
            %app.ECGNormalLamp.Position = uint16([ECG_Normal_x*w,ECG_Normal_y*h,ECG_Normal_w*w,ECG_Normal_h*h]);
            %app.ECGHighLamp.Position = uint16([ECG_High_x*w,ECG_High_y*h,ECG_High_w*w,ECG_High_h*h]);
            app.ECGLowLabel.Position = uint16([ECG_LowL_x*w,ECG_LowL_y*h,ECG_LowL_w*w,ECG_LowL_h*h]);
            app.ECGNormalLabel.Position = uint16([ECG_NormalL_x*w,ECG_NormalL_y*h,ECG_NormalL_w*w,ECG_NormalL_h*h]);
            app.ECGHighLabel.Position = uint16([ECG_HighL_x*w,ECG_HighL_y*h,ECG_HighL_w*w,ECG_HighL_h*h]);
            app.ECGLastTimeLabel.Position = uint16([ECG_LastTimeL_x*w,ECG_LastTimeL_y*h,ECG_LastTimeL_w*w,ECG_LastTimeL_h*h]);
            app.ECGAxesDetail.Position = uint16([ECG_AxesDetail_x*w,ECG_AxesDetail_y*h,ECG_AxesDetail_w*w,ECG_AxesDetail_h*h]);
            app.ECGStatisticsButtonGroup.Position = uint16([ECG_Statistics_x*w,ECG_Statistics_y*h,ECG_Statistics_w*w,ECG_Statistics_h*h]);
            
            % 3.4) Steps Panel
            app.StepsPanel.Position = uint16([StepsPanel_x*w,StepsPanel_y*h,StepsPanel_w*w,StepsPanel_h*h]);
            app.StepsGauge.Position = uint16([Steps_Gauge_x*w,Steps_Gauge_y*h,Steps_Gauge_w*w,Steps_Gauge_w*w]);%Steps_Gauge_h*h]);
            app.StepsAxesDetail1.Position = uint16([Steps_Axes1_x*w,Steps_Axes1_y*h,Steps_Axes1_w*w,Steps_Axes1_h*h]);
            app.StepsAxesDetail2.Position = uint16([Steps_Axes2_x*w,Steps_Axes2_y*h,Steps_Axes2_w*w,Steps_Axes2_h*h]);
            app.StepsDailyGoalLabel.Position = uint16([Steps_DailyGoalL_x*w,Steps_DailyGoalL_y*h,Steps_DailyGoalL_w*w,Steps_DailyGoalL_h*h]);
            app.StepsProgressLabel.Position = uint16([Steps_ProgressL_x*w,Steps_ProgressL_y*h,Steps_ProgressL_w*w,Steps_ProgressL_h*h]);
            app.StepsLampLB.Position = uint16([Steps_LampLB_x*w,Steps_LampLB_y*h,Steps_LampLB_w*w,Steps_LampLB_w*w]);%Steps_LampLB_h*h]);
            app.StepsLampLF.Position = uint16([Steps_LampLF_x*w,Steps_LampLF_y*h,Steps_LampLF_w*w,Steps_LampLF_w*w]);%Steps_LampLF_h*h]);
            app.StepsLampRB.Position = uint16([Steps_LampRB_x*w,Steps_LampRB_y*h,Steps_LampRB_w*w,Steps_LampRB_w*w]);%Steps_LampRB_h*h]);
            app.StepsLampRF.Position = uint16([Steps_LampRF_x*w,Steps_LampRF_y*h,Steps_LampRF_w*w,Steps_LampRF_w*w]);%Steps_LampRF_h*h]);
            
            
            %app.ECGButton.Text = 'hello';
            
            if(strcmp(app.SelectUserPanel.Visible, 'on'))
               
                selectUserWidth = 260;
                selectUserHeight = 270;
                selectUserX = w/2 - selectUserWidth/2;
                selectUserY = h/2 - selectUserHeight/2;
                
                app.SelectUserPanel.Position = uint16([selectUserX,selectUserY,selectUserWidth,selectUserHeight]);
                
            end
            
            
        end

        % Callback function
        function MoredetailsButtonPushed(app, event)
            app.ExtraInformationECGPanel.Visible = 'on';
            app.MoredetailsButton.Visible = 'off';
        end

        % Callback function
        function MoredetailsButton_2Pushed(app, event)
            app.ExtraInformationPPGPanel.Visible = 'on';
            app.MoredetailsButton_2.Visible = 'off';
        end

        % Value changed function: PPGButton
        function PPGButtonValueChanged(app, event)
            value = app.PPGButton.Value;
            if value == true
                app.PPGButton.BackgroundColor = [0.8, 0.8, 0.8];
                app.ECGButton.BackgroundColor = [0.15, 0.18, 0.23];
                app.StepsButton.BackgroundColor = [0.15, 0.18, 0.23];
                
                app.ECGButton.Value = false;
                app.StepsButton.Value = false;
                
                app.GraphPanel.Visible = 'off';
                app.ECGPanel.Visible = 'off';
                app.StepsPanel.Visible = 'off';
                
                app.PPGPanel.Visible = 'on';
            else
                app.PPGButton.BackgroundColor = [0.15, 0.18, 0.23];
                
                app.PPGPanel.Visible = 'off';
                app.GraphPanel.Visible = 'on';
            end
        end

        % Selection changed function: PPGStatisticsButtonGroup
        function PPGStatisticsButtonGroupSelectionChanged(app, event)
            selectedButton = app.PPGStatisticsButtonGroup.SelectedObject;
            if app.PPGButton30m.Value == true
                app.PPGLastTimeLabel.Text = 'Last 30 mins:';
            elseif app.PPGButton2h.Value == true
                app.PPGLastTimeLabel.Text = 'Last 2 hrs:';
            else
                app.PPGLastTimeLabel.Text = 'Last 6 hrs:';
            end 
        end

        % Value changed function: SelectUserListBox
        function SelectUserListBoxValueChanged(app, event)
            value = app.SelectUserListBox.Value;
            
            if(strcmp(value, 'Create New User...'))
               % Questions
                prompt = {'Enter your name:','Enter your age:','Enter your height(m):','Enter your weight(kg):'};
                dlg_title = ' Age ';
                num_lines = 1;
                defaultans = {'Jan Vermeulen','65','1.75','75'};
                answer = inputdlg(prompt,dlg_title,num_lines,defaultans);
                
                app.userName = answer{1};
                %app.userAge = str2double(answer{2});
                %app.userHeight = str2double(answer{3});
                %app.userWeight = str2double(answer{4}); 
                
                app.SelectUserListBox.Items = [app.SelectUserListBox.Items app.userName];
                app.SelectUserListBox.Value = {};
                
            else
                
                app.userName = value{1};
                app.HeaderUserEditField.Value = app.userName;
                
                %disp(value);
                
                app.HeaderPanel.Visible = 'on';
                app.MainPanel.Visible = 'on';
                app.SelectUserPanel.Visible = 'off';
                
                
                % Set ECG Gauge
                app.ECGGauge.ScaleColorLimits = [0 60; 60 220-app.userAge; 220-app.userAge 240 ];
                app.ECGGauge.ScaleColors = [0 0 1; 0 1 0; 1 0 0];


            end
            
        end

        % Value changed function: StepsButton
        function StepsButtonValueChanged(app, event)
            value = app.StepsButton.Value;
            if value == true
                app.StepsButton.BackgroundColor = [0.8, 0.8, 0.8];
                app.ECGButton.BackgroundColor = [0.15, 0.18, 0.23];
                app.PPGButton.BackgroundColor = [0.15, 0.18, 0.23];
                
                app.ECGButton.Value = false;
                app.PPGButton.Value = false;
                
                app.GraphPanel.Visible = 'off';
                app.PPGPanel.Visible = 'off';
                app.ECGPanel.Visible = 'off';
                
                app.StepsPanel.Visible = 'on';
            else
                app.StepsButton.BackgroundColor = [0.15, 0.18, 0.23];
                
                app.StepsPanel.Visible = 'off';
                app.GraphPanel.Visible = 'on';
            end
        end

        % Button pushed function: UserSaveButton
        function UserSaveButtonPushed(app, event)
            app.userAge = app.UserAgeEditField.Value;
            app.userHeight = app.UserHeightEditField.Value;
            app.userWeight = app.UserWeightEditField.Value;
            
            app.UserDetailsPanel.Visible = 'off';
        end

        % Button pushed function: UserXButton
        function UserXButtonPushed(app, event)
            app.UserDetailsPanel.Visible = 'off';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HealthVisionUIFigure = uifigure;
            app.HealthVisionUIFigure.Name = 'HealthVision';
            app.HealthVisionUIFigure.SizeChangedFcn = createCallbackFcn(app, @HealthVisionUIFigureSizeChanged, true);
            app.HeaderPanel = uipanel(app.HealthVisionUIFigure);
            app.HeaderPanel.Visible = 'off';
            app.HeaderUserEditField = uieditfield(app.HeaderPanel, 'text');
            app.HeaderUserEditField.Editable = 'off';
            app.HeaderUserLabel = uilabel(app.HeaderPanel);
            app.HeaderUserLabel.Text = 'User';
            app.HeaderTitleAxes = uiaxes(app.HeaderPanel);
            app.HeaderTitleAxes.XTick = [];
            app.HeaderTitleAxes.YTick = [];
            app.HeaderUserDetailsButton = uibutton(app.HeaderPanel, 'push');
            app.HeaderUserDetailsButton.ButtonPushedFcn = createCallbackFcn(app, @HeaderUserDetailsButtonPushed, true);
            app.HeaderUserDetailsButton.Text = {'User'; 'Details'};
            app.MainPanel = uipanel(app.HealthVisionUIFigure);
            app.MainPanel.Visible = 'off';
            app.ButtonPanel = uipanel(app.MainPanel);
            app.ECGButton = uibutton(app.ButtonPanel, 'state');
            app.ECGButton.ValueChangedFcn = createCallbackFcn(app, @ECGButtonValueChanged, true);
            app.ECGButton.Text = '0 BPM';
            app.PPGLabel = uilabel(app.ButtonPanel);
            app.PPGLabel.Text = 'SPO2';
            app.PPGButton = uibutton(app.ButtonPanel, 'state');
            app.PPGButton.ValueChangedFcn = createCallbackFcn(app, @PPGButtonValueChanged, true);
            app.PPGButton.Text = '98.8 %';
            app.StepsLabel = uilabel(app.ButtonPanel);
            app.StepsLabel.Text = 'STEPS';
            app.StepsButton = uibutton(app.ButtonPanel, 'state');
            app.StepsButton.ValueChangedFcn = createCallbackFcn(app, @StepsButtonValueChanged, true);
            app.StepsButton.Text = {'4730'; 'Steps'};
            app.ECGLabel = uilabel(app.ButtonPanel);
            app.ECGLabel.Text = 'ECG';
            app.InfoPanel = uipanel(app.MainPanel);
            app.PPGPanel = uipanel(app.InfoPanel);
            app.PPGPanel.Visible = 'off';
            app.PPGAxesDetail1 = uiaxes(app.PPGPanel);
            ylabel(app.PPGAxesDetail1, 'Red')
            app.PPGAxesDetail3 = uiaxes(app.PPGPanel);
            xlabel(app.PPGAxesDetail3, 'Time')
            ylabel(app.PPGAxesDetail3, 'SPO2 (%)')
            app.PPGLowLamp = uilamp(app.PPGPanel);
            app.PPGLowLamp.Visible = 'off';
            app.PPGNormalLamp = uilamp(app.PPGPanel);
            app.PPGNormalLamp.Visible = 'off';
            app.PPGStatisticsButtonGroup = uibuttongroup(app.PPGPanel);
            app.PPGStatisticsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PPGStatisticsButtonGroupSelectionChanged, true);
            app.PPGStatisticsButtonGroup.Title = 'Statistics';
            app.PPGButton30m = uiradiobutton(app.PPGStatisticsButtonGroup);
            app.PPGButton30m.Text = '30 min';
            app.PPGButton30m.Value = true;
            app.PPGButton2h = uiradiobutton(app.PPGStatisticsButtonGroup);
            app.PPGButton2h.Text = '2 hr';
            app.PPGButton6h = uiradiobutton(app.PPGStatisticsButtonGroup);
            app.PPGButton6h.Text = '6 hr';
            app.PPGLastTimeLabel = uilabel(app.PPGPanel);
            app.PPGLastTimeLabel.Text = 'Last 30 mins:';
            app.PPGMinEditField = uieditfield(app.PPGPanel, 'numeric');
            app.PPGMinEditField.Editable = 'off';
            app.PPGAvgEditField = uieditfield(app.PPGPanel, 'numeric');
            app.PPGAvgEditField.Editable = 'off';
            app.PPGAxesDetail2 = uiaxes(app.PPGPanel);
            ylabel(app.PPGAxesDetail2, 'Infrared')
            app.PPGMaxEditField = uieditfield(app.PPGPanel, 'numeric');
            app.PPGMaxEditField.Editable = 'off';
            app.PPGMinLabel = uilabel(app.PPGPanel);
            app.PPGMinLabel.Text = 'Min';
            app.PPGMaxLabel = uilabel(app.PPGPanel);
            app.PPGMaxLabel.Text = 'Max';
            app.PPGAvgLabel = uilabel(app.PPGPanel);
            app.PPGAvgLabel.Text = 'Avg';
            app.PPGLowLabel = uilabel(app.PPGPanel);
            app.PPGLowLabel.Text = 'Low: 0 - 90 %';
            app.PPGNormalLabel = uilabel(app.PPGPanel);
            app.PPGNormalLabel.Text = 'Normal: 90 - 100 %';
            app.PPGGauge = uigauge(app.PPGPanel, 'linear');
            app.ECGPanel = uipanel(app.InfoPanel);
            app.ECGPanel.Visible = 'off';
            app.ECGAvgEditField = uieditfield(app.ECGPanel, 'numeric');
            app.ECGAvgEditField.Editable = 'off';
            app.ECGLastTimeLabel = uilabel(app.ECGPanel);
            app.ECGLastTimeLabel.Text = 'Last 30 mins:';
            app.ECGMinEditField = uieditfield(app.ECGPanel, 'numeric');
            app.ECGMinEditField.Editable = 'off';
            app.ECGMaxEditField = uieditfield(app.ECGPanel, 'numeric');
            app.ECGMaxEditField.Editable = 'off';
            app.ECGLowLamp = uilamp(app.ECGPanel);
            app.ECGLowLamp.Visible = 'off';
            app.ECGNormalLamp = uilamp(app.ECGPanel);
            app.ECGNormalLamp.Visible = 'off';
            app.ECGHighLamp = uilamp(app.ECGPanel);
            app.ECGHighLamp.Visible = 'off';
            app.ECGAxesDetail = uiaxes(app.ECGPanel);
            xlabel(app.ECGAxesDetail, 'Time')
            ylabel(app.ECGAxesDetail, 'Heart Rate (bpm)')
            app.ECGMinLabel = uilabel(app.ECGPanel);
            app.ECGMinLabel.Text = 'Min';
            app.ECGMaxLabel = uilabel(app.ECGPanel);
            app.ECGMaxLabel.Text = 'Max';
            app.ECGAvgLabel = uilabel(app.ECGPanel);
            app.ECGAvgLabel.Text = 'Avg';
            app.ECGLowLabel = uilabel(app.ECGPanel);
            app.ECGLowLabel.Text = 'Low: 0 - 40 bpm';
            app.ECGNormalLabel = uilabel(app.ECGPanel);
            app.ECGNormalLabel.Text = 'Normal: 40 - 180 bpm';
            app.ECGHighLabel = uilabel(app.ECGPanel);
            app.ECGHighLabel.Text = 'High: 180+ bpm';
            app.ECGStatisticsButtonGroup = uibuttongroup(app.ECGPanel);
            app.ECGStatisticsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ECGStatisticsButtonGroupSelectionChanged, true);
            app.ECGStatisticsButtonGroup.Title = 'Statistics';
            app.ECGButton30m = uiradiobutton(app.ECGStatisticsButtonGroup);
            app.ECGButton30m.Text = '30 min';
            app.ECGButton30m.Value = true;
            app.ECGButton2h = uiradiobutton(app.ECGStatisticsButtonGroup);
            app.ECGButton2h.Text = '2 hr';
            app.ECGButton6h = uiradiobutton(app.ECGStatisticsButtonGroup);
            app.ECGButton6h.Text = '6 hr';
            app.Label = uilabel(app.ECGPanel);
            app.Label.Text = '';
            app.ECGGauge = uigauge(app.ECGPanel, 'semicircular');
            app.ECGGauge.Limits = [0 240];
            app.ECGGauge.MajorTicks = [0 40 80 120 160 200 240];
            app.GraphPanel = uipanel(app.InfoPanel);
            app.ECGAxesHome = uiaxes(app.GraphPanel);
            app.ECGAxesHome.XTick = [];
            app.PPGAxesHome = uiaxes(app.GraphPanel);
            app.PPGAxesHome.XTick = [];
            app.StepsAxesHome = uiaxes(app.GraphPanel);
            app.StepsPanel = uipanel(app.InfoPanel);
            app.StepsPanel.Visible = 'off';
            app.StepsGauge = uigauge(app.StepsPanel, 'circular');
            app.StepsDailyGoalLabel = uilabel(app.StepsPanel);
            app.StepsDailyGoalLabel.Text = 'Daily Goal: 6000 steps';
            app.StepsAxesDetail1 = uiaxes(app.StepsPanel);
            app.StepsAxesDetail1.XTick = [0 0.5 1];
            app.StepsAxesDetail1.YTick = [0 0.5 1];
            app.StepsLampLB = uilamp(app.StepsPanel);
            app.StepsLampLF = uilamp(app.StepsPanel);
            app.StepsLampRB = uilamp(app.StepsPanel);
            app.StepsLampRF = uilamp(app.StepsPanel);
            app.StepsAxesDetail2 = uiaxes(app.StepsPanel);
            app.StepsProgressLabel = uilabel(app.StepsPanel);
            app.StepsProgressLabel.Text = 'Progress (%)';
            app.UserDetailsPanel = uipanel(app.HealthVisionUIFigure);
            app.UserDetailsPanel.Visible = 'off';
            app.UserTitleLabel = uilabel(app.UserDetailsPanel);
            app.UserTitleLabel.Text = 'User Details';
            app.UserNameLabel = uilabel(app.UserDetailsPanel);
            app.UserNameLabel.Text = 'Name:';
            app.UserNameEditField = uieditfield(app.UserDetailsPanel, 'text');
            app.UserNameEditField.Editable = 'off';
            app.UserAgeLabel = uilabel(app.UserDetailsPanel);
            app.UserAgeLabel.Text = 'Age:';
            app.UserHeightLabel = uilabel(app.UserDetailsPanel);
            app.UserHeightLabel.Text = 'Height (m):';
            app.UserWeightLabel = uilabel(app.UserDetailsPanel);
            app.UserWeightLabel.Text = 'Weight (kg):';
            app.UserBMILabel = uilabel(app.UserDetailsPanel);
            app.UserBMILabel.Text = 'BMI:';
            app.UserXButton = uibutton(app.UserDetailsPanel, 'push');
            app.UserXButton.ButtonPushedFcn = createCallbackFcn(app, @UserXButtonPushed, true);
            app.UserXButton.Text = 'X';
            app.UserSaveButton = uibutton(app.UserDetailsPanel, 'push');
            app.UserSaveButton.ButtonPushedFcn = createCallbackFcn(app, @UserSaveButtonPushed, true);
            app.UserSaveButton.Text = 'Save';
            app.UserAgeEditField = uieditfield(app.UserDetailsPanel, 'numeric');
            app.UserAgeEditField.Limits = [0 120];
            app.UserHeightEditField = uieditfield(app.UserDetailsPanel, 'numeric');
            app.UserHeightEditField.Limits = [0 120];
            app.UserWeightEditField = uieditfield(app.UserDetailsPanel, 'numeric');
            app.UserWeightEditField.Limits = [0 120];
            app.SelectUserPanel = uipanel(app.HealthVisionUIFigure);
            app.SelectUserListBox = uilistbox(app.SelectUserPanel);
            app.SelectUserListBox.Items = {'Create New User...', 'Camille Stevens', 'Fien Verschraegen', 'Katrien Van Daele'};
            app.SelectUserListBox.Multiselect = 'on';
            app.SelectUserListBox.ValueChangedFcn = createCallbackFcn(app, @SelectUserListBoxValueChanged, true);
            app.SelectUserListBox.Value = {};
            app.SelectUserLabel = uilabel(app.SelectUserPanel);
            app.SelectUserLabel.Text = 'Select User';
        end
    end

    methods (Access = public)

        % Construct app
        function app = HealthVision

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HealthVisionUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HealthVisionUIFigure)
        end
    end
end