classdef layoutwitheditfields < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SmarthomecaremonitorUIFigure    matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        EditField_2                     matlab.ui.control.NumericEditField
        EditField_3                     matlab.ui.control.NumericEditField
        SPO2Label                       matlab.ui.control.Label
        DRUKLabel                       matlab.ui.control.Label
        EditField_4                     matlab.ui.control.NumericEditField
        ECGLabel                        matlab.ui.control.Label
        ExtrainformationheartbeatPanel  matlab.ui.container.Panel
        AverageEditFieldLabel           matlab.ui.control.Label
        AverageEditField                matlab.ui.control.NumericEditField
        LasthourLabel                   matlab.ui.control.Label
        MinEditFieldLabel               matlab.ui.control.Label
        MinEditField                    matlab.ui.control.NumericEditField
        MaxEditFieldLabel               matlab.ui.control.Label
        MaxEditField                    matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        Gauge                           matlab.ui.control.SemicircularGauge
        LowLampLabel                    matlab.ui.control.Label
        LowLamp                         matlab.ui.control.Lamp
        NormalLampLabel                 matlab.ui.control.Label
        NormalLamp                      matlab.ui.control.Lamp
        HighLampLabel                   matlab.ui.control.Label
        HighLamp                        matlab.ui.control.Lamp
        UIAxes2                         matlab.ui.control.UIAxes
        XButton                         matlab.ui.control.Button
        TimeButtonGroup                 matlab.ui.container.ButtonGroup
        minButton                       matlab.ui.control.RadioButton
        minButton_2                     matlab.ui.control.RadioButton
        hourButton                      matlab.ui.control.RadioButton
        LastminuteLabel                 matlab.ui.control.Label
        Last10minutesLabel              matlab.ui.control.Label
        ExtrainformationSPO2Panel       matlab.ui.container.Panel
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes6                         matlab.ui.control.UIAxes
        EditField_5                     matlab.ui.control.NumericEditField
        LowLamp_2Label                  matlab.ui.control.Label
        LowLamp_2                       matlab.ui.control.Lamp
        NormalLamp_2Label               matlab.ui.control.Label
        NormalLamp_2                    matlab.ui.control.Lamp
        TimeButtonGroup_2               matlab.ui.container.ButtonGroup
        minButton_3                     matlab.ui.control.RadioButton
        minButton_4                     matlab.ui.control.RadioButton
        hourButton_2                    matlab.ui.control.RadioButton
        LastminuteLabel_2               matlab.ui.control.Label
        MinEditField_2Label             matlab.ui.control.Label
        MinEditField_2                  matlab.ui.control.NumericEditField
        MaxEditField_2Label             matlab.ui.control.Label
        MaxEditField_2                  matlab.ui.control.NumericEditField
        AverageEditField_2Label         matlab.ui.control.Label
        AverageEditField_2              matlab.ui.control.NumericEditField
        XButton_2                       matlab.ui.control.Button
        Last10minutesLabel_2            matlab.ui.control.Label
        LasthourLabel_2                 matlab.ui.control.Label
        SmarthomecaremonitorPanel       matlab.ui.container.Panel
        AgeEditFieldLabel               matlab.ui.control.Label
        AgeEditField                    matlab.ui.control.NumericEditField
        UserEditFieldLabel              matlab.ui.control.Label
        UserEditField                   matlab.ui.control.EditField
        LengthEditFieldLabel            matlab.ui.control.Label
        LengthEditField                 matlab.ui.control.NumericEditField
        weightEditFieldLabel            matlab.ui.control.Label
        weightEditField                 matlab.ui.control.NumericEditField
        MoredetailsButton               matlab.ui.control.Button
        MoredetailsButton_2             matlab.ui.control.Button
        MoredetailsButton_3             matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: MoredetailsButton
        function MoredetailsButtonPushed(app, event)
            app.ExtrainformationheartbeatPanel.Visible = 'on';
            app.MoredetailsButton.Visible = 'off';
        end

        % Button pushed function: XButton
        function XButtonPushed(app, event)
            app.ExtrainformationheartbeatPanel.Visible = 'off';
            app.MoredetailsButton.Visible = 'on'; 
        end

        % Selection changed function: TimeButtonGroup
        function TimeButtonGroupSelectionChanged(app, event)
            selectedButton = app.TimeButtonGroup.SelectedObject;
            if app.minButton.Value == true
                app.LastminuteLabel.Visible = 'on';
                app.Last10minutesLabel.Visible = 'off';
                app.LasthourLabel.Visible = 'off';
            elseif app.minButton_2.Value == true
                app.LastminuteLabel.Visible = 'off';
                app.Last10minutesLabel.Visible = 'on';
                app.LasthourLabel.Visible = 'off';
            else
                app.LastminuteLabel.Visible = 'off';
                app.Last10minutesLabel.Visible = 'off';
                app.LasthourLabel.Visible = 'on';
            end
        end

        % Button pushed function: MoredetailsButton_2
        function MoredetailsButton_2Pushed(app, event)
            app.ExtrainformationSPO2Panel.Visible = 'on';
            app.MoredetailsButton_2.Visible = 'off';
        end

        % Button pushed function: XButton_2
        function XButton_2Pushed(app, event)
            app.ExtrainformationSPO2Panel.Visible = 'off';
            app.MoredetailsButton_2.Visible = 'on';
        end

        % Selection changed function: TimeButtonGroup_2
        function TimeButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.TimeButtonGroup_2.SelectedObject;
            if app.minButton_3.Value == true
                app.LastminuteLabel_2.Visible = 'on';
                app.Last10minutesLabel_2.Visible = 'off';
                app.LasthourLabel_2.Visible = 'off';
            elseif app.minButton_4.Value == true
                app.LastminuteLabel_2.Visible = 'off';
                app.Last10minutesLabel_2.Visible = 'on';
                app.LasthourLabel_2.Visible = 'off';
            else
                app.LastminuteLabel_2.Visible = 'off';
                app.Last10minutesLabel_2.Visible = 'off';
                app.LasthourLabel_2.Visible = 'on';
            end 
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SmarthomecaremonitorUIFigure = uifigure;
            app.SmarthomecaremonitorUIFigure.Name = 'Smart home care monitor';
            setAutoResize(app, app.SmarthomecaremonitorUIFigure, true)
            app.UIAxes = uiaxes(app.SmarthomecaremonitorUIFigure);
            app.UIAxes_2 = uiaxes(app.SmarthomecaremonitorUIFigure);
            app.UIAxes_3 = uiaxes(app.SmarthomecaremonitorUIFigure);
            app.EditField_2 = uieditfield(app.SmarthomecaremonitorUIFigure, 'numeric');
            app.EditField_3 = uieditfield(app.SmarthomecaremonitorUIFigure, 'numeric');
            app.SPO2Label = uilabel(app.SmarthomecaremonitorUIFigure);
            app.SPO2Label.Text = 'SPO2';
            app.DRUKLabel = uilabel(app.SmarthomecaremonitorUIFigure);
            app.DRUKLabel.Text = 'DRUK';
            app.EditField_4 = uieditfield(app.SmarthomecaremonitorUIFigure, 'numeric');
            app.ECGLabel = uilabel(app.SmarthomecaremonitorUIFigure);
            app.ECGLabel.Text = 'ECG';
            app.ExtrainformationheartbeatPanel = uipanel(app.SmarthomecaremonitorUIFigure);
            app.ExtrainformationheartbeatPanel.Title = 'Extra information heartbeat';
            app.ExtrainformationheartbeatPanel.Visible = 'off';
            app.AverageEditFieldLabel = uilabel(app.ExtrainformationheartbeatPanel);
            app.AverageEditFieldLabel.Text = 'Average';
            app.AverageEditField = uieditfield(app.ExtrainformationheartbeatPanel, 'numeric');
            app.LasthourLabel = uilabel(app.ExtrainformationheartbeatPanel);
            app.LasthourLabel.Visible = 'off';
            app.LasthourLabel.Text = 'Last hour';
            app.MinEditFieldLabel = uilabel(app.ExtrainformationheartbeatPanel);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.ExtrainformationheartbeatPanel, 'numeric');
            app.MaxEditFieldLabel = uilabel(app.ExtrainformationheartbeatPanel);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.ExtrainformationheartbeatPanel, 'numeric');
            app.Label = uilabel(app.ExtrainformationheartbeatPanel);
            app.Label.Text = '';
            app.Gauge = uigauge(app.ExtrainformationheartbeatPanel, 'semicircular');
            app.Gauge.Limits = [0 240];
            app.Gauge.MajorTicks = [0 40 80 120 160 200 240];
            app.LowLampLabel = uilabel(app.ExtrainformationheartbeatPanel);
            app.LowLampLabel.Text = 'Low';
            app.LowLamp = uilamp(app.ExtrainformationheartbeatPanel);
            app.NormalLampLabel = uilabel(app.ExtrainformationheartbeatPanel);
            app.NormalLampLabel.Text = 'Normal';
            app.NormalLamp = uilamp(app.ExtrainformationheartbeatPanel);
            app.HighLampLabel = uilabel(app.ExtrainformationheartbeatPanel);
            app.HighLampLabel.Text = 'High';
            app.HighLamp = uilamp(app.ExtrainformationheartbeatPanel);
            app.UIAxes2 = uiaxes(app.ExtrainformationheartbeatPanel);
            xlabel(app.UIAxes2, 'Time');
            ylabel(app.UIAxes2, 'Heartbeat');
            app.XButton = uibutton(app.ExtrainformationheartbeatPanel, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
            app.XButton.Text = 'X';
            app.TimeButtonGroup = uibuttongroup(app.ExtrainformationheartbeatPanel);
            app.TimeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TimeButtonGroupSelectionChanged, true);
            app.TimeButtonGroup.Title = 'Time';
            app.minButton = uiradiobutton(app.TimeButtonGroup);
            app.minButton.Text = '1 min';
            app.minButton.Value = true;
            app.minButton_2 = uiradiobutton(app.TimeButtonGroup);
            app.minButton_2.Text = '10 min';
            app.hourButton = uiradiobutton(app.TimeButtonGroup);
            app.hourButton.Text = '1 hour';
            app.LastminuteLabel = uilabel(app.ExtrainformationheartbeatPanel);
            app.LastminuteLabel.Text = 'Last minute';
            app.Last10minutesLabel = uilabel(app.ExtrainformationheartbeatPanel);
            app.Last10minutesLabel.Visible = 'off';
            app.Last10minutesLabel.Text = 'Last 10 minutes';
            app.ExtrainformationSPO2Panel = uipanel(app.SmarthomecaremonitorUIFigure);
            app.ExtrainformationSPO2Panel.Title = 'Extra information SPO2';
            app.ExtrainformationSPO2Panel.Visible = 'off';
            app.UIAxes3 = uiaxes(app.ExtrainformationSPO2Panel);
            app.UIAxes4 = uiaxes(app.ExtrainformationSPO2Panel);
            app.UIAxes6 = uiaxes(app.ExtrainformationSPO2Panel);
            app.EditField_5 = uieditfield(app.ExtrainformationSPO2Panel, 'numeric');
            app.LowLamp_2Label = uilabel(app.ExtrainformationSPO2Panel);
            app.LowLamp_2Label.Text = 'Low';
            app.LowLamp_2 = uilamp(app.ExtrainformationSPO2Panel);
            app.NormalLamp_2Label = uilabel(app.ExtrainformationSPO2Panel);
            app.NormalLamp_2Label.Text = 'Normal';
            app.NormalLamp_2 = uilamp(app.ExtrainformationSPO2Panel);
            app.TimeButtonGroup_2 = uibuttongroup(app.ExtrainformationSPO2Panel);
            app.TimeButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @TimeButtonGroup_2SelectionChanged, true);
            app.TimeButtonGroup_2.Title = 'Time';
            app.minButton_3 = uiradiobutton(app.TimeButtonGroup_2);
            app.minButton_3.Text = '1 min';
            app.minButton_3.Value = true;
            app.minButton_4 = uiradiobutton(app.TimeButtonGroup_2);
            app.minButton_4.Text = '10 min';
            app.hourButton_2 = uiradiobutton(app.TimeButtonGroup_2);
            app.hourButton_2.Text = '1 hour';
            app.LastminuteLabel_2 = uilabel(app.ExtrainformationSPO2Panel);
            app.LastminuteLabel_2.Text = 'Last minute';
            app.MinEditField_2Label = uilabel(app.ExtrainformationSPO2Panel);
            app.MinEditField_2Label.Text = 'Min';
            app.MinEditField_2 = uieditfield(app.ExtrainformationSPO2Panel, 'numeric');
            app.MaxEditField_2Label = uilabel(app.ExtrainformationSPO2Panel);
            app.MaxEditField_2Label.Text = 'Max';
            app.MaxEditField_2 = uieditfield(app.ExtrainformationSPO2Panel, 'numeric');
            app.AverageEditField_2Label = uilabel(app.ExtrainformationSPO2Panel);
            app.AverageEditField_2Label.Text = 'Average';
            app.AverageEditField_2 = uieditfield(app.ExtrainformationSPO2Panel, 'numeric');
            app.XButton_2 = uibutton(app.ExtrainformationSPO2Panel, 'push');
            app.XButton_2.ButtonPushedFcn = createCallbackFcn(app, @XButton_2Pushed, true);
            app.XButton_2.Text = 'X';
            app.Last10minutesLabel_2 = uilabel(app.ExtrainformationSPO2Panel);
            app.Last10minutesLabel_2.Visible = 'off';
            app.Last10minutesLabel_2.Text = 'Last 10 minutes';
            app.LasthourLabel_2 = uilabel(app.ExtrainformationSPO2Panel);
            app.LasthourLabel_2.Visible = 'off';
            app.LasthourLabel_2.Text = 'Last hour';
            app.SmarthomecaremonitorPanel = uipanel(app.SmarthomecaremonitorUIFigure);
            app.SmarthomecaremonitorPanel.Title = 'Smart home care monitor';
            app.AgeEditFieldLabel = uilabel(app.SmarthomecaremonitorPanel);
            app.AgeEditFieldLabel.Text = 'Age';
            app.AgeEditField = uieditfield(app.SmarthomecaremonitorPanel, 'numeric');
            app.AgeEditField.Value = 55;
            app.UserEditFieldLabel = uilabel(app.SmarthomecaremonitorPanel);
            app.UserEditFieldLabel.Text = 'User';
            app.UserEditField = uieditfield(app.SmarthomecaremonitorPanel, 'text');
            app.LengthEditFieldLabel = uilabel(app.SmarthomecaremonitorPanel);
            app.LengthEditFieldLabel.Text = 'Length';
            app.LengthEditField = uieditfield(app.SmarthomecaremonitorPanel, 'numeric');
            app.weightEditFieldLabel = uilabel(app.SmarthomecaremonitorPanel);
            app.weightEditFieldLabel.Text = 'weight';
            app.weightEditField = uieditfield(app.SmarthomecaremonitorPanel, 'numeric');
            app.MoredetailsButton = uibutton(app.SmarthomecaremonitorUIFigure, 'push');
            app.MoredetailsButton.ButtonPushedFcn = createCallbackFcn(app, @MoredetailsButtonPushed, true);
            app.MoredetailsButton.Text = 'More details';
            app.MoredetailsButton_2 = uibutton(app.SmarthomecaremonitorUIFigure, 'push');
            app.MoredetailsButton_2.ButtonPushedFcn = createCallbackFcn(app, @MoredetailsButton_2Pushed, true);
            app.MoredetailsButton_2.Text = 'More details';
            app.MoredetailsButton_3 = uibutton(app.SmarthomecaremonitorUIFigure, 'push');
            app.MoredetailsButton_3.Text = 'More details';
        end
    end

    methods (Access = public)

        % Construct app
        function app = layoutwitheditfields()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SmarthomecaremonitorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SmarthomecaremonitorUIFigure)
        end
    end
end